classdef GUI_Det_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        panel                matlab.ui.container.Panel
        uipanel1             matlab.ui.container.Panel
        pushbutton_Aceptar   matlab.ui.control.Button
        pushbutton_Limpiar   matlab.ui.control.Button
        fila                 matlab.ui.control.EditField
        colum                matlab.ui.control.EditField
        pushbutton_Calcular  matlab.ui.control.Button
        TextArea             matlab.ui.control.TextArea
        text_Resultado       matlab.ui.control.Label
        uitable1             matlab.ui.control.Table
        pushbutton_Regresar  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GUI_DETERMINANTE_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GUI_DETERMINANTE (see VARARGIN)
            
            % Choose default command line output for GUI_DETERMINANTE
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            set(handles.pushbutton_Calcular,'visible','off');
        end

        % Button pushed function: pushbutton_Regresar
        function pushbutton_Regresar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_Regresar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            GUI_Menu_App
            closereq();
        end

        % Callback function
        function edit_MatrizValueChanged(app, event)
           % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
           
        end

        % Button pushed function: pushbutton_Limpiar
        function pushbutton_LimpiarButtonPushed(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            set(handles.fila,'String','');
            set(handles.colum,'String','');         
            set(handles.textArea_Resultado,'string',''); 
            
        end

        % Button pushed function: pushbutton_Aceptar
        function pushbutton_AceptarButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            filas=str2double(get(handles.fila,'String'));
            columnas=str2double(get(handles.colum,'String'));
            if(isnan(columnas) || isnan(filas))
                warndlg('El campo de filas y columnas no debe estar vacío, con letras o caracteres especiales','Precaución');
            else
                if(filas==0 || columnas==0)
                    warndlg('El campo de filas y columnas debe ser mayor a 0','Precaución');
                    set(handles.fila,'String','');
                    set(handles.colum,'String',''); 
                else
                    if(filas==columnas)
                        set(handles.pushbutton_Calcular,'visible','on');
                        tamano=cell(filas,columnas);
                        tamano(:,:)={'0'};
                        set(handles.uitable1,'Data',tamano);
                        set(handles.uitable1,'ColumnEditable',true(1,columnas));
                    else
                        set(handles.pushbutton_Calcular,'visible','off');
                        warndlg('La matriz no es cuadrada','Precaución');
                        set(handles.colum,'String','');
                        set(handles.fila,'String','');
                    end 
                end
            end       
        end

        % Button pushed function: pushbutton_Calcular
        function pushbutton_CalcularButtonPushed(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            valor=get(handles.uitable1,'data');
            valor=str2double(valor);
            if(isnan(valor))
                warndlg('Debe ingresar valores para calcular el determinante','Precaución');
            else
                determinanteR= determinante(valor);
                s=round(determinanteR);
                set(handles.textArea_Resultado,'string',num2str(s));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GUI_DETERMINANTE';
            app.figure1.Tag = 'figure1';
            app.panel = uipanel(app.figure1);
            app.panel.Title = 'Determinante de una matriz';
            app.uipanel1 = uipanel(app.panel);
            app.uipanel1.Title = 'Definir tamaño de la matriz';
            app.uipanel1.Tag = 'uipanel1';
            app.pushbutton_Aceptar = uibutton(app.uipanel1, 'push');
            app.pushbutton_Aceptar.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_AceptarButtonPushed, true);
            app.pushbutton_Aceptar.Tag = 'pushbutton_Aceptar';
            app.pushbutton_Aceptar.Text = 'Aceptar';
            app.pushbutton_Limpiar = uibutton(app.uipanel1, 'push');
            app.pushbutton_Limpiar.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_LimpiarButtonPushed, true);
            app.pushbutton_Limpiar.Tag = 'pushbutton_Limpiar';
            app.pushbutton_Limpiar.Text = 'Limpiar';
            app.fila = uieditfield(app.uipanel1, 'text');
            app.fila.Tag = 'fila';
            app.colum = uieditfield(app.uipanel1, 'text');
            app.colum.Tag = 'colum';
            app.pushbutton_Calcular = uibutton(app.panel, 'push');
            app.pushbutton_Calcular.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_CalcularButtonPushed, true);
            app.pushbutton_Calcular.Tag = 'pushbutton_Calcular';
            app.pushbutton_Calcular.Text = 'Calcular';
            app.TextArea = uitextarea(app.panel);
            app.TextArea.Tag = 'textArea_Resultado';
            app.TextArea.Editable = 'off';
            app.text_Resultado = uilabel(app.panel);
            app.text_Resultado.Tag = 'text_Resultado';
            app.text_Resultado.Text = 'Resultado';
            app.uitable1 = uitable(app.panel);
            app.uitable1.Tag = 'uitable1';
            app.pushbutton_Regresar = uibutton(app.figure1, 'push');
            app.pushbutton_Regresar.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_Regresar_Callback, true);
            app.pushbutton_Regresar.Tag = 'pushbutton_Regresar';
            app.pushbutton_Regresar.Text = 'Regresar';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Det_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GUI_DETERMINANTE_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end